classdef StaticKinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StaticKinetUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        BloomLabWebsiteMenu             matlab.ui.container.Menu
        OtherProgramsMenu               matlab.ui.container.Menu
        GitHubMenu                      matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        N_Nuf2StructurePanel            matlab.ui.container.Panel
        N_Nuf2NumberofFluorophoreSpinnerLabel  matlab.ui.control.Label
        N_Nuf2LengthnmSpinnerLabel      matlab.ui.control.Label
        N_Nuf2LengthnmSpinner           matlab.ui.control.Spinner
        N_Nuf2NumberofFluorophoreSpinner  matlab.ui.control.Spinner
        N_Nuf2ColorChannelDropDownLabel  matlab.ui.control.Label
        N_Nuf2ColorChannelDropDown      matlab.ui.control.DropDown
        OutputPanel                     matlab.ui.container.Panel
        FileFolderNameStringLabel       matlab.ui.control.Label
        FileFolderNameStringEditField   matlab.ui.control.EditField
        GenerateXMLCheckBox             matlab.ui.control.CheckBox
        SaveMATFileCheckBox             matlab.ui.control.CheckBox
        MicroscopeSimulatorParametersPanel  matlab.ui.container.Panel
        GainLabel                       matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        OffsetLabel                     matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        GaussianNoiseSDLabel            matlab.ui.control.Label
        GaussianNoiseSDEditField        matlab.ui.control.NumericEditField
        MaxVoxelIntensityLabel          matlab.ui.control.Label
        MaxVoxelIntensityEditField      matlab.ui.control.NumericEditField
        GenerateTIFsCheckBox            matlab.ui.control.CheckBox
        NumberofPlanesSpinnerLabel      matlab.ui.control.Label
        NumberofPlanesSpinner           matlab.ui.control.Spinner
        SpacingBetweenPlanesnmSpinnerLabel  matlab.ui.control.Label
        SpacingBetweenPlanesnmSpinner   matlab.ui.control.Spinner
        WidthpixelsSpinnerLabel         matlab.ui.control.Label
        WidthpixelsSpinner              matlab.ui.control.Spinner
        HeightpixelsSpinnerLabel        matlab.ui.control.Label
        HeightpixelsSpinner             matlab.ui.control.Spinner
        PixelSizenmSpinnerLabel         matlab.ui.control.Label
        PixelSizenmSpinner              matlab.ui.control.Spinner
        PreProcessTIFsCheckBox          matlab.ui.control.CheckBox
        StatusOutputPanel               matlab.ui.container.Panel
        GenerateOutputButton            matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        AxisLimPanel                    matlab.ui.container.Panel
        YLowerLimnmEditFieldLabel       matlab.ui.control.Label
        YLowerLimnmEditField1           matlab.ui.control.NumericEditField
        YUpperLimnmEditFieldLabel       matlab.ui.control.Label
        YUpperLimnmEditField1           matlab.ui.control.NumericEditField
        XLowerLimnmEditFieldLabel       matlab.ui.control.Label
        XLowerLimnmEditField1           matlab.ui.control.NumericEditField
        XUpperLimnmEditFieldLabel       matlab.ui.control.Label
        XUpperLimnmEditField1           matlab.ui.control.NumericEditField
        YLowerLimnmEditField_2Label     matlab.ui.control.Label
        YLowerLimnmEditField2           matlab.ui.control.NumericEditField
        YUpperLimnmEditField_2Label     matlab.ui.control.Label
        YUpperLimnmEditField2           matlab.ui.control.NumericEditField
        XLowerLimnmEditField_2Label     matlab.ui.control.Label
        XLowerLimnmEditField2           matlab.ui.control.NumericEditField
        XUpperLimnmEditField_2Label     matlab.ui.control.Label
        XUpperLimnmEditField2           matlab.ui.control.NumericEditField
        YLowerLimnmEditField_3Label     matlab.ui.control.Label
        YLowerLimnmEditField3           matlab.ui.control.NumericEditField
        YUpperLimnmEditField_3Label     matlab.ui.control.Label
        YUpperLimnmEditField3           matlab.ui.control.NumericEditField
        XLowerLimnmEditField_3Label     matlab.ui.control.Label
        XLowerLimnmEditField3           matlab.ui.control.NumericEditField
        XUpperLimnmEditField_3Label     matlab.ui.control.Label
        XUpperLimnmEditField3           matlab.ui.control.NumericEditField
        GraphPanel                      matlab.ui.container.Panel
        DViewPopOutButton               matlab.ui.control.Button
        AxisLimitsButtonGroup           matlab.ui.container.ButtonGroup
        AutomaticButton                 matlab.ui.control.RadioButton
        FixedButton                     matlab.ui.control.RadioButton
        NumberofComplexesSwitchLabel    matlab.ui.control.Label
        NumberofComplexesSwitch         matlab.ui.control.Switch
        Spc29StructurePanel             matlab.ui.container.Panel
        Spc29TubuleDiameternmSpinnerLabel  matlab.ui.control.Label
        Spc29TubuleDiameternmSpinner    matlab.ui.control.Spinner
        Spc29StructureDiameternmSpinnerLabel  matlab.ui.control.Label
        Spc29StructureDiameternmSpinner  matlab.ui.control.Spinner
        Spc29NumberofFluorophoreSpinnerLabel  matlab.ui.control.Label
        Spc29NumberofFluorophoreSpinner  matlab.ui.control.Spinner
        Spc29ColorChannelDropDownLabel  matlab.ui.control.Label
        Spc29ColorChannelDropDown       matlab.ui.control.DropDown
        Spc29NumberofTubulesSpinnerLabel  matlab.ui.control.Label
        Spc29NumberofTubulesSpinner     matlab.ui.control.Spinner
        Spc29LengthnmSpinnerLabel       matlab.ui.control.Label
        Spc29LengthnmSpinner            matlab.ui.control.Spinner
        Spc29DistancetonmSpinnerLabel   matlab.ui.control.Label
        Spc29DistancetonmSpinner        matlab.ui.control.Spinner
        MicrotubuleDiameternmLabel      matlab.ui.control.Label
        MicrotubuleDiameternmSpinner    matlab.ui.control.Spinner
        StructureDiameternmSpinnerLabel  matlab.ui.control.Label
        StructureDiameternmSpinner      matlab.ui.control.Spinner
        NumberofChromosomesSpinnerLabel  matlab.ui.control.Label
        NumberofChromosomesSpinner      matlab.ui.control.Spinner
        StaggerDropDownLabel            matlab.ui.control.Label
        StaggerDropDown                 matlab.ui.control.DropDown
        RangenmSpinnerLabel             matlab.ui.control.Label
        RangenmSpinner                  matlab.ui.control.Spinner
        N_Nuf2CheckBox                  matlab.ui.control.CheckBox
        Spc29CheckBox                   matlab.ui.control.CheckBox
        KinetochoreProteinPanel         matlab.ui.container.Panel
        LengthofUnboundnmSpinnerLabel   matlab.ui.control.Label
        LengthofUnboundnmSpinner        matlab.ui.control.Spinner
        NumberofComplexesSpinnerLabel   matlab.ui.control.Label
        NumberofComplexesSpinner        matlab.ui.control.Spinner
        DistanceofTapernmSpinnerLabel   matlab.ui.control.Label
        DistanceofTapernmSpinner        matlab.ui.control.Spinner
        NumberofBoundComplexesSpinnerLabel  matlab.ui.control.Label
        NumberofBoundComplexesSpinner   matlab.ui.control.Spinner
        RadialDispofTapernmSpinnerLabel  matlab.ui.control.Label
        RadialDispofTapernmSpinner      matlab.ui.control.Spinner
        AngleRangeforUnbounddegSpinnerLabel  matlab.ui.control.Label
        LowerAngleSpinner               matlab.ui.control.Spinner
        UpperAngleLabel                 matlab.ui.control.Label
        UpperAngleSpinner               matlab.ui.control.Spinner
        ofLengthMarkedSpinnerLabel      matlab.ui.control.Label
        ofLengthMarkedSpinner           matlab.ui.control.Spinner
        KinetochoreColorChannelLabel    matlab.ui.control.Label
        KinetochoreColorChannel         matlab.ui.control.DropDown
        KinetochoreCheckBox             matlab.ui.control.CheckBox
        RotationPanel                   matlab.ui.container.Panel
        RotationParametersPanel         matlab.ui.container.ButtonGroup
        FixedRotationButton             matlab.ui.control.RadioButton
        RandomRotationButton            matlab.ui.control.RadioButton
        RotationCheckBox                matlab.ui.control.CheckBox
        RotationLabel                   matlab.ui.control.Label
        RandomRotationParametersdegPanel  matlab.ui.container.Panel
        LowLimSpinnerLabel              matlab.ui.control.Label
        LowLimSpinner                   matlab.ui.control.Spinner
        UpLimSpinnerLabel               matlab.ui.control.Label
        UpLimSpinner                    matlab.ui.control.Spinner
        FixedRotationParametersdegPanel  matlab.ui.container.Panel
        XSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        ZSpinner                        matlab.ui.control.Spinner
    end


    properties (Access = private)
        N_Nuf2PointInfo % Contains information about the points that make up the N_Nuf2 structure
        KinetochorePointInfo % Contains information about the points that make up the kinetochore complex
        Spc29PointInfo % Continas information about the points that make up the Spc29 structure
        MasterPointInfo % Contains information about the points that make up the entire simulation
        LengthSim % Array of possible stagger lengths acquired from running Coupled Model Crosslinked Simulation by Stevens et al.
        BioDistances % Description
        BioDistanceCorrection % Description
        Rotation % Description
        Stagger % Description
    end

    methods (Access = private)
        function GeneralParametreCheck(app)
            MainParameterCheck(app)
            KinetochoreValueChangeCheck(app)
            OutputParameterCheck(app)
            OutputParameterLocationCheck(app)
            AxisParameterCheck(app)            
        end
    
        function MainParameterCheck(app)
            if strcmp(app.StaggerDropDown.Value, 'Random')
                app.RangenmSpinner.Visible = "on";
                app.RangenmSpinnerLabel.Visible = "on";
            else
                app.RangenmSpinner.Visible = "off";
                app.RangenmSpinnerLabel.Visible = "off";
            end  
            if app.RotationCheckBox.Value == false
                app.RotationParametersPanel.Visible = 'off';
                app.FixedRotationParametersdegPanel.Visible = 'off';
                app.RandomRotationParametersdegPanel.Visible = 'off';
            else
                app.RotationParametersPanel.Visible = 'on';
                if app.FixedRotationButton.Value == true
                    app.FixedRotationParametersdegPanel.Visible = 'on';
                    app.RandomRotationParametersdegPanel.Visible = 'off';
                else
                    app.FixedRotationParametersdegPanel.Visible = 'off';
                    app.RandomRotationParametersdegPanel.Visible = 'on';
                end
            end
        end
    
        function KinetochoreValueChangeCheck(app)
            if app.NumberofComplexesSpinner.Value < app.NumberofBoundComplexesSpinner.Value
                app.NumberofBoundComplexesSpinner.Value = app.NumberofComplexesSpinner.Value;
            end
            if app.LowerAngleSpinner.Value > app.UpperAngleSpinner.Value
                tempUpper = app.UpperAngleSpinner.Value;
                app.UpperAngleSpinner.Value = app.LowerAngleSpinner.Value;
                app.LowerAngleSpinner.Value = tempUpper;
            end
        end
            
    
        function OutputParameterCheck(app)
            if app.GenerateXMLCheckBox.Value == true
                app.MicroscopeSimulatorParametersPanel.Visible = 'on';
            else
                app.MicroscopeSimulatorParametersPanel.Visible = 'off';
            end
            if isempty(app.FileFolderNameStringEditField.Value) || (app.GenerateXMLCheckBox.Value == false && app.SaveMATFileCheckBox.Value == false)
                app.GenerateOutputButton.Enable = "off";
            else
                app.GenerateOutputButton.Enable = "on";
            end
            if app.GenerateTIFsCheckBox.Value == true
                app.PreProcessTIFsCheckBox.Enable = "on";
            else
                app.PreProcessTIFsCheckBox.Enable = "off";
            end 
        end
    
        function OutputParameterLocationCheck(app)
            if app.GenerateXMLCheckBox.Value == true
                app.StatusOutputPanel.Position = [2 7 296 103];
            else
                app.StatusOutputPanel.Position = [2 231 296 103];
            end
        end
    
        function AxisParameterCheck(app)
            if app.AutomaticButton.Value == true
                app.GraphPanel.Position = [493 330 151 85];
                app.AxisLimPanel.Visible = "off";
            else
                app.GraphPanel.Position = [493 389 151 85];
                app.AxisLimPanel.Visible = "on";
            end
        end
    
        function SaveBioDistanceData(app)
            app.BioDistances = [-192,-1344,-640,-256,-576,-192,-768,192,-448,-1920,-512,-576,-960,-832,-128,-896,-512,-256,-704,-704,-256,-512,-256,-832,-1216,-64,-576,-768,-1024,-256,-768,-256,0,-960,-1088,-640,-832,-1024,-768,-1088,-704,-1216,-1408,-832,-896,-704,-320,-896,-512,-512,-1024,-832,-448,-256,-640,-256,-768,-576,192,-192,-384,-640,-768,-960,-768,64,-320,-704,-448,-192,-128,64,-640,-640,-448,-832,-832,-832,-960,-384,-128,-1152,-128,-256,-256,-384,-576,-896,-576,-768,-896,-640,-1024,-832,-896,-704,-320,-896,-512,-512,-192,-768,-768,-512,-960,-576,-448,-1728,-1088,128,-704,-256,-256,-832,-64,-1728,-192,-640,-1088,-832,-960,-704,-256,-1024,-960,-1152,-768,-1024,-384,-64,-512,-512,64,-640,-832,-512,-576,-832,-320,192,64,-1664,-832,-768,-384,-1152,-896,-768,64,-768,-448,-576,-192,0,-640,-768,-704,-256,-192,64,-192,256,-1152,-832,-640,-576,-576,-128,-896,-384,0,-320,-320,-448,-320,-128,-896,-1280,-448,-512,-448,-896,-832,-576,-1152,-320,-832,-256,-576,-512,-128,-704,-320,-832,-320,192,64,-1664,-832,-768,-384,-1152,-896,-768,64,-768,-448,-576,-192,0,-640,-768,0,-1024,-896,-1344,-1600,-256,128,-1216,-704,128,-1216,-960,-768,-832,-832,-320,-640,-640,0,-704,64,-896,-320,-768,-1024,-1216,-768,-448,-832,-960,-960,128,-960,-704,-256,-256,-768,-576,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-1152,0,-320,-320,-448,-704,-704,-512,-1408,-1088,-576,-128,0,-1024,128,-1152,-192,-320,-576,-384,-128,64,-704,-1024,-384,-320,-1024,-832,-448,-1152,-640,-832,-704,-192,-832,128,-768,-576,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-576,-768,-576,-256,-320,-512,-448,-128,-1344,-960,-1024,-128,-832,-1024,-704,-384,-1728,-832,-640,-1024,-1152,-256,-256,-448,-512,-768,-448,-512,-1600,-1216,-448,-512,-768,-768,-320,-448,-896,192,-832,-960,-960,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-1216,-704,-576,-512,-768,-1088,-256,-1152,-384,-384,-896,-832,-576,-448,-576,-640,128,-896,-384,-512,-704,-960,-960,-1152,-384,-576,-960,-320,-832,-960,-960,-704,-1024,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,128,-1152,-128,-1024,-128,-832,-192,-256,320,-448,-1344,-832,-1216,-512,-768,64,-576,-960,-896,-512,-1024,192,-1280,-192,-832,64,-768,-960,-320,-832,-960,-960,-704,-1024,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-1024,-768,-960,64,-1024,-320,128,-256,-512,-192,64,-192,-320,-448,-896,0,-1216,-960,-768,-576,-576,-192,-1216,-512,-1280,-384,-704,64,-512,-512,-704,-384,-896,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-960,192,-128,-1152,-640,-448,-384,-192,-640,-64,-704,-1024,-704,-640,-1216,-704,-512,-256,-512,-640,-256,-704,-256,-832,-704,-576,-256,-704,64,-512,-512,-704,-384,-896,-320,-256,-832,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-192,-448,128,-192,-192,-384,-192,-576,-1280,-448,-1280,-384,-576,-960,-320,-128,-64,-896,-896,-512,-384,0,-192,-640,-640,-384,-384,-320,-640,-128,-960,-960,-576,-384,-320,-448,-192,0,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-832,-384,-320,64,-960,-1024,256,-832,-768,-320,-768,-576,-64,0,-448,-960,-1088,-384,-192,-640,-256,-1472,-1216,-640,-192,-448,-512,-256,-448,-768,-960,-384,-448,-320,-576,-960,-1024,-320,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,128,-832,-384,-256,-1024,-384,-640,-256,-832,64,-896,64,-640,-320,-704,-320,-1024,-576,-960,0,-1088,-832,-192,-1088,-1024,-512,-640,-832,-256,-960,-512,-640,-384,-704,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-128,-1088,-384,-1088,-192,-512,-960,-320,-960,-704,-512,-960,-64,-320,-128,-704,-640,-1152,-256,-576,-768,-512,-256,-1152,-704,-896,-512,-384,-576,128,128,-704,-384,-704,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-576,-1024,-704,-768,-768,-640,-448,-1280,-1024,-704,-896,-1728,-256,-512,-1024,-64,-384,-128,-1024,-576,-832,-128,-320,-832,-640,64,-256,-384,-192,-768,-704,-960,-64,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-640,-640,-448,-128,-704,-448,-768,-1216,-448,-832,-192,-832,-384,-832,-768,-704,-448,-640,128,64,-448,-640,-768,-64,-704,-1280,-896,-192,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-768,-448,-384,-1152,192,-896,-384,-960,-384,-704,-512,-512,-192,-128,-832,-448,-1024,-256,-128,-576,-256,-192,-192,-832,-192,-1216,-1280,-896,-192,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-1152,-832,-640,-256,-1088,-512,-384,-1280,-320,-512,-256,-384,-1280,-1024,-896,-1216,-640,-640,-640,-1024,128,-256,-1856,-128,-1088,-512,-448,-576,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-960,-512,-192,128,-384,-960,-256,-128,-448,-640,-576,-960,-576,-896,-1024,-512,128,-960,-640,-640,-896,-512,-768,-128,-256,-896,-320,-448,-576,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-192,-128,-1024,-1344,-896,-320,-576,-768,-384,-832,-576,-128,-320,-512,-896,-1600,-256,-256,-448,-256,-704,-1216,-1280,128,-1088,-704,-1088,-512,-256,-64,-640,-512,-832,-1088,-1088,-1216,-448,-640,-704,-768,-448,-896,-1024,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-640,-704,-576,-1280,-1536,-448,-768,-1344,-192,-768,-896,-448,-1088,-768,-256,-1408,-704,-1088,-640,-640,-640,-1152,-1216,-1152,-768,-128,-576,-192,-512,-320,-640,-576,-832,192,-512,-832,-832,64,-448,-1088,-256,-256,-1344,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768,-832,-512,-384,-704,-256,-704,-768,-768,-384,-1344,-832,-64,-704,-256,-576,-768,0,-832,-1152,-768,-704,-704,-768,-1024,-960,-128,-960,-192,-512,-320,-640,-576,-832,192,-512,-832,-832,64,-448,-1088,-256,-256,-1344,-576,-1216,-1216,-896,-640,-1408,-960,-320,-576,-192,0,-640,-768;-960,-192,-384,1536,-384,-960,640,-1280,-448,-128,576,-768,1216,128,-1024,448,-384,-1344,-64,-640,-768,832,-832,512,704,-704,0,-896,-448,640,256,576,-384,64,-64,640,256,-1088,64,-64,1216,-768,-192,512,704,-1280,704,384,576,1088,832,-896,768,576,0,-576,704,-64,-640,-960,1024,-640,384,-384,-64,-1280,-1088,512,-384,-512,-896,-960,-1024,448,832,0,0,576,64,-1344,832,-256,-512,512,-1408,-1408,576,448,1024,384,128,-576,-576,512,704,-1280,704,384,576,1088,1216,-192,320,0,-640,512,960,256,768,-576,1088,1280,-960,1280,-1024,-64,704,-448,128,384,448,320,-1088,64,832,128,640,512,640,-640,704,-128,-960,320,-640,-704,-512,-256,896,-1024,-576,704,-448,448,-768,-192,-448,832,-640,256,-1024,-320,-448,-768,256,832,-1152,-704,-640,-832,-768,-1408,1088,448,384,-704,0,704,-704,576,-576,640,1024,-512,768,-1088,576,-576,960,384,448,-192,320,-704,-384,448,64,-1088,320,256,448,192,704,-256,896,-1024,-576,704,-448,448,-768,-192,-448,832,-640,256,-1024,-320,-448,-768,256,832,-896,-448,448,448,512,960,-768,-256,704,-1024,64,-64,-512,448,512,448,0,-1152,-768,-384,-640,704,-768,-448,-256,-128,960,1088,320,64,-256,-832,576,64,448,640,-576,832,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-704,-704,-1088,704,-192,-1408,-704,-576,-128,-64,-448,-960,-896,-512,-1152,192,576,832,256,-320,-448,-448,960,64,1024,1088,-832,-1216,-448,-448,768,-192,1088,-448,-64,-576,-576,832,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,256,-832,960,-640,1152,1088,576,-1280,-256,64,-128,448,-832,-512,320,1408,0,-576,320,320,-512,768,-1216,-704,-768,-576,1408,704,-64,-256,1216,768,-640,576,-896,-128,-1152,-960,1280,-640,-448,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-1216,64,-448,-384,960,128,64,704,-1216,1344,832,-64,64,-1152,-512,0,384,-1280,-320,-1088,-1024,-384,768,256,-128,448,320,64,-384,-1024,-512,-256,320,-256,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-448,-448,-1280,-64,1024,1088,-576,768,-960,-1088,-192,64,-256,768,384,-1216,384,384,-960,576,-64,-768,-256,-448,-320,-960,704,64,-384,-1024,-512,-256,320,-256,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-320,64,-576,-128,-768,-576,1536,-1088,-1088,640,576,-896,-576,-1024,768,-256,-896,384,0,-128,-192,1024,-896,128,448,-1024,-512,128,-576,-256,-512,192,1344,832,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,-896,960,960,1280,-832,704,704,256,-640,512,192,960,768,-704,-704,-896,-1216,-576,-704,-1024,64,-576,960,576,-896,-832,128,-576,-256,-512,192,1344,832,-768,960,-256,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-960,960,-384,-448,-704,576,448,-768,128,192,384,320,-576,1024,-64,1024,-1024,-576,384,-448,-640,896,-704,-640,-1088,256,-704,512,576,832,384,-448,384,-192,576,1472,384,-1280,-512,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,512,-448,448,-512,64,704,-1216,-1088,640,-512,-768,448,-704,768,1152,-320,-448,576,-384,576,256,896,-768,-384,576,-640,-64,-1024,704,-768,256,-896,-512,960,-448,-960,-832,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-1024,768,1152,896,-192,-640,0,-704,-448,-640,256,-896,-704,-768,-576,-832,-576,-192,-320,-640,384,-576,832,576,192,1280,640,128,-960,-576,-1152,-832,-896,-1152,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,640,-832,448,-832,-768,1024,960,-448,-512,-832,-512,1088,-896,-1344,-1024,576,960,-448,-960,1536,-832,-576,-960,192,640,384,-320,704,-320,-1152,-960,448,-896,-1152,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-640,576,-512,-832,-384,1280,-704,0,-448,-128,1280,-512,-1088,-1664,-640,-896,1088,-640,-320,-832,-512,960,-1920,-64,-384,-1088,-768,896,832,-448,-640,-640,-576,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,0,832,1280,-448,-704,192,640,128,320,-384,576,640,832,-576,-1024,512,256,-1088,576,-896,-832,-1088,1088,-64,-704,-896,-1600,320,-1088,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,512,-512,64,-832,-768,-512,640,1216,960,832,-640,384,1024,-896,-128,1024,576,448,576,-768,-768,640,832,-128,-832,256,-1600,320,-1088,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-640,-192,512,1408,576,64,320,-320,384,640,-192,-576,192,256,320,-768,-576,-256,704,-192,256,-960,1024,640,-768,-1152,320,-896,-576,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-64,1152,-576,-1088,1024,192,-512,-896,640,-256,-448,-256,384,-320,-576,960,-960,0,64,-832,-192,-768,-384,-448,-1024,192,-896,-896,-576,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,576,-1408,128,320,448,-704,-832,-256,896,128,-512,-832,704,-320,-320,-192,1088,-1664,640,1408,256,128,576,-640,576,-64,832,448,768,-896,832,640,-64,-320,256,704,704,-320,-192,256,-512,128,-64,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-896,320,832,-256,896,576,-832,384,-832,1280,576,768,-1152,-384,-576,64,-448,192,-256,-192,576,384,704,320,256,832,704,704,960,832,512,512,-192,-1088,384,64,768,-1088,-576,768,-704,384,512,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832,-448,704,-1088,896,-768,-1216,-384,448,-704,64,640,-704,-704,1280,576,-512,-960,-448,128,0,64,192,192,1408,-1600,1216,-1216,704,960,832,512,512,-192,-1088,384,64,768,-1088,-576,768,-704,384,512,896,0,256,768,-704,576,-832,640,-320,-448,-768,256,832;0,-300,0,0,0,0,-1200,-300,-900,-300,0,0,-300,300,0,600,0,300,-300,0,0,0,0,0,0,0,600,0,-600,0,0,300,600,300,0,600,0,-300,300,0,-300,0,0,-900,-300,-600,600,-600,0,-300,0,900,-300,0,0,0,300,0,-300,300,0,600,0,0,0,300,600,-600,300,-600,-300,300,300,600,-1200,0,300,0,0,900,-300,0,0,-900,0,300,-300,-300,-300,0,600,-300,0,-900,-300,-600,600,-600,0,-300,300,0,0,0,0,0,0,0,300,0,-300,-900,0,-300,0,-600,300,600,300,0,300,0,300,-300,300,300,600,0,-600,-600,-300,0,0,300,0,-900,600,0,-600,-900,600,600,-600,0,0,-300,0,-600,0,-300,0,300,-600,0,-600,-300,-300,0,0,0,0,300,-300,0,300,-600,0,0,0,0,0,0,0,600,-300,0,300,-300,300,300,300,0,300,0,600,600,600,-600,600,0,-300,-300,0,0,-600,-900,600,600,-600,0,0,-300,0,-600,0,-300,0,300,-600,0,-600,-300,300,0,0,-900,0,0,600,0,0,-600,0,0,300,0,-600,300,300,-600,0,0,-300,-300,0,0,0,600,0,-1800,0,300,300,0,0,0,-300,0,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,300,900,0,300,0,300,-600,0,-900,-300,0,0,0,-300,0,-300,-300,300,-900,600,0,-300,0,0,-900,600,-300,300,-300,300,-300,300,300,-300,-300,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,900,-600,0,300,0,0,300,300,300,-300,600,300,-300,300,0,-300,300,300,0,0,-300,0,300,-300,-300,0,0,300,300,-300,600,-300,300,-300,0,0,0,0,0,-300,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-900,600,-600,0,0,-600,0,0,0,-300,0,0,0,300,0,0,0,-600,300,0,-600,-300,-600,300,-300,0,600,600,300,1200,-300,0,-900,-600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,-300,-300,-300,-300,0,-300,0,300,0,0,0,0,300,300,0,-300,600,-300,300,0,600,300,0,0,0,0,600,300,1200,-300,0,-900,-600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-900,0,300,0,0,0,0,0,-300,300,-300,0,900,-1200,-300,-300,0,0,600,900,300,300,300,-600,-300,0,-900,0,0,-300,-600,300,600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,0,-300,0,0,0,300,-300,0,0,0,0,600,300,-300,0,-600,0,-900,-300,-600,-300,0,-300,0,-300,-300,-900,0,0,-300,-600,300,600,300,300,-600,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-600,0,300,-600,0,300,900,-600,-600,-300,0,0,300,0,-900,0,0,0,-600,0,-300,0,0,0,-300,-600,300,0,0,-300,0,600,-600,-600,0,600,0,900,300,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,600,0,600,0,900,0,300,0,0,-300,0,0,0,-900,600,-600,900,-300,-300,300,0,0,0,-300,300,300,600,0,-900,0,-300,-300,0,300,0,0,0,0,-600,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-600,0,-900,600,0,0,0,0,-300,0,-300,600,0,0,-300,300,0,300,0,0,0,-300,0,300,0,-900,1500,-300,0,600,-300,300,0,300,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-600,-300,-300,600,600,0,-300,-300,0,0,0,0,-300,0,-300,0,-300,0,0,-300,-300,-600,-900,-600,-600,0,-300,0,600,300,0,0,300,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-300,-600,0,300,600,300,0,0,-600,0,0,0,-600,0,300,0,-600,0,0,0,-600,600,-600,0,300,0,0,0,-300,0,0,-300,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,600,-300,300,600,-300,-1500,0,900,0,0,-900,-300,-300,0,0,0,300,300,0,-300,0,0,0,-300,-900,0,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,-600,300,0,300,300,0,-300,-600,0,0,600,0,0,-300,-600,300,0,300,0,0,-300,300,0,-300,0,0,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,300,300,0,0,300,0,-300,0,0,600,-300,0,-300,-300,0,-900,0,-300,0,300,-600,0,0,-600,900,600,300,-600,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,0,-300,300,300,0,0,0,0,0,-300,-300,0,0,0,-600,0,0,0,0,0,300,0,0,600,-600,0,-600,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,-300,0,-300,-300,-600,-600,0,300,-300,0,300,0,-600,300,0,-300,0,600,0,300,0,1200,-300,0,0,600,-600,0,0,300,300,0,0,-600,-300,0,0,0,0,0,-600,0,-300,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,600,-300,-300,-300,300,0,0,300,0,600,-600,0,0,-600,0,0,-600,0,300,0,0,900,-300,0,-600,600,-300,0,-300,-300,0,0,-300,600,-900,300,0,0,-300,-600,-300,300,600,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300,0,600,300,-300,-600,300,600,0,0,0,-300,0,0,0,600,-600,900,-600,0,0,300,-300,300,-600,0,300,600,0,-300,-300,0,0,-300,600,-900,300,0,0,-300,-600,-300,300,600,-900,0,0,-600,-300,-600,0,0,300,-600,0,-600,-300];
        end
    
        function GenerateSimuLinkData(app)
                sim('Coupled_Model_crosslinked_intext.slx')
                app.LengthSim = kMtsOut(500:end,:);
        end
    
        function UpdateData(app)
            StructureCalculate(app)
            app.MasterPointInfo.CoordPoints = [];
            app.MasterPointInfo.Color = [];
            if app.N_Nuf2CheckBox.Value == true
                app.MasterPointInfo.CoordPoints = horzcat(app.MasterPointInfo.CoordPoints, app.N_Nuf2PointInfo.CoordPoints);
                app.MasterPointInfo.Color = horzcat(app.MasterPointInfo.Color, app.N_Nuf2PointInfo.Color);
            end
            if app.Spc29CheckBox.Value == true
                app.MasterPointInfo.CoordPoints = horzcat(app.MasterPointInfo.CoordPoints, app.Spc29PointInfo.CoordPoints);
                app.MasterPointInfo.Color = horzcat(app.MasterPointInfo.Color, app.Spc29PointInfo.Color);
            end
            if app.KinetochoreCheckBox.Value == true
                app.MasterPointInfo.CoordPoints = horzcat(app.MasterPointInfo.CoordPoints, app.KinetochorePointInfo.CoordPoints);
                app.MasterPointInfo.Color = horzcat(app.MasterPointInfo.Color, app.KinetochorePointInfo.Color);
            end
        end
    
        function StructureCalculate(app)
            UpdateRandomVariables(app)
            N_Nuf2Calculate(app)
            KinetochoreCalculate(app)
            Spc29Calculate(app)
        end
    
        function UpdateRandomVariables(app)
            app.BioDistanceCorrection = app.BioDistances(:,ceil(rand()*length(app.BioDistances)))/2;
            if strcmp(app.NumberofComplexesSwitch.Value, '1')
                NoC = 1;
            else
                NoC = 2;
            end
            if app.RotationCheckBox.Value == true
                app.Rotation = rand(app.NumberofChromosomesSpinner.Value*NoC,3);
                if app.FixedRotationButton.Value == true
                    app.Rotation(:,1) = app.XSpinner.Value;
                    app.Rotation(:,2) = app.YSpinner.Value;
                    app.Rotation(:,3) = app.ZSpinner.Value;
                else
                    rangediff = app.UpLimSpinner.Value - app.LowLimSpinner.Value;
                    app.Rotation = app.Rotation*rangediff + app.LowLimSpinner.Value;
                end
            end
            if ~strcmp(app.StaggerDropDown.Value, 'None')
                if strcmp(app.StaggerDropDown.Value, 'Random')
                    app.Stagger = (rand(1,app.NumberofChromosomesSpinner.Value*NoC)-0.5)*(app.RangenmSpinner.Value*2);
                else
                    app.Stagger = (app.LengthSim(ceil(rand(1,app.NumberofChromosomesSpinner.Value*NoC)*size(app.LengthSim,1)),ceil(rand(1,app.NumberofChromosomesSpinner.Value*NoC)*size(app.LengthSim,2)))-3.5e-07)*1e9;
                end
            end
        end

        function N_Nuf2Calculate(app)
            if strcmp(app.NumberofComplexesSwitch.Value, '1')
                N_Nuf2Structure1(app)
            else
                N_Nuf2Structure2(app)
            end
            app.N_Nuf2PointInfo.Color = repmat(convertCharsToStrings(app.N_Nuf2ColorChannelDropDown.Value),1,size(app.N_Nuf2PointInfo.CoordPoints,2));
        end
        
        function N_Nuf2Structure1(app)
            RotationCheck = (strcmp(app.RotationParametersPanel.Visible,'on')) & (app.RotationCheckBox.Value == true);
            app.N_Nuf2PointInfo.CoordPoints = StructurePointsGenerate(app,1,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.N_Nuf2NumberofFluorophoreSpinner.Value,app.N_Nuf2LengthnmSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,RotationCheck);
        end
    
        function N_Nuf2Structure2(app)
            RotationCheck = (strcmp(app.RotationParametersPanel.Visible,'on')) & (app.RotationCheckBox.Value == true);
            app.N_Nuf2PointInfo.CoordPoints = StructurePointsGenerate(app,1,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.N_Nuf2NumberofFluorophoreSpinner.Value,app.N_Nuf2LengthnmSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,RotationCheck);
            app.N_Nuf2PointInfo.CoordPoints = (RotationMatrixGenerate(app,app.BioDistanceCorrection)*app.N_Nuf2PointInfo.CoordPoints) - app.BioDistanceCorrection;
            app.N_Nuf2PointInfo.CoordPoints2 = StructurePointsGenerate(app,1+app.NumberofChromosomesSpinner.Value,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.N_Nuf2NumberofFluorophoreSpinner.Value,app.N_Nuf2LengthnmSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,RotationCheck);
            app.N_Nuf2PointInfo.CoordPoints2 = (RotationMatrixGenerate(app,-app.BioDistanceCorrection)*app.N_Nuf2PointInfo.CoordPoints2) + app.BioDistanceCorrection;
            app.N_Nuf2PointInfo.CoordPoints = horzcat(app.N_Nuf2PointInfo.CoordPoints, app.N_Nuf2PointInfo.CoordPoints2);
        end
    
        function KinetochoreCalculate(app)
            if strcmp(app.NumberofComplexesSwitch.Value, '1')
                KinetochoreStructure1(app)
            else
                KinetochoreStructure2(app)
            end
            app.KinetochorePointInfo.Color = repmat(convertCharsToStrings(app.KinetochoreColorChannel.Value),1,size(app.KinetochorePointInfo.CoordPoints,2));
        end
    
        function KinetochoreStructure1(app)
            app.KinetochorePointInfo.CoordPoints = KinetochoreGenerate(app,1,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.NumberofComplexesSpinner.Value,app.DistanceofTapernmSpinner.Value,app.RadialDispofTapernmSpinner.Value,app.NumberofBoundComplexesSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,app.ofLengthMarkedSpinner.Value,app.LengthofUnboundnmSpinner.Value,app.UpperAngleSpinner.Value,app.LowerAngleSpinner.Value);
        end
    
        function KinetochoreStructure2(app)
            app.KinetochorePointInfo.CoordPoints = KinetochoreGenerate(app,1,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.NumberofComplexesSpinner.Value,app.DistanceofTapernmSpinner.Value,app.RadialDispofTapernmSpinner.Value,app.NumberofBoundComplexesSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,app.ofLengthMarkedSpinner.Value,app.LengthofUnboundnmSpinner.Value,app.UpperAngleSpinner.Value,app.LowerAngleSpinner.Value);
            app.KinetochorePointInfo.CoordPoints = (RotationMatrixGenerate(app,app.BioDistanceCorrection)*app.KinetochorePointInfo.CoordPoints) - app.BioDistanceCorrection;
            app.KinetochorePointInfo.CoordPoints2 = KinetochoreGenerate(app,1+app.NumberofChromosomesSpinner.Value,app.NumberofChromosomesSpinner.Value,app.StaggerDropDown.Value,app.StructureDiameternmSpinner.Value,app.NumberofComplexesSpinner.Value,app.DistanceofTapernmSpinner.Value,app.RadialDispofTapernmSpinner.Value,app.NumberofBoundComplexesSpinner.Value,app.MicrotubuleDiameternmSpinner.Value,app.ofLengthMarkedSpinner.Value,app.LengthofUnboundnmSpinner.Value,app.UpperAngleSpinner.Value,app.LowerAngleSpinner.Value);
            app.KinetochorePointInfo.CoordPoints2 = (RotationMatrixGenerate(app,-app.BioDistanceCorrection)*app.KinetochorePointInfo.CoordPoints2) + app.BioDistanceCorrection;
            app.KinetochorePointInfo.CoordPoints = horzcat(app.KinetochorePointInfo.CoordPoints, app.KinetochorePointInfo.CoordPoints2);
            
        end
    
        function Spc29Calculate(app)
            if strcmp(app.NumberofComplexesSwitch.Value, '1')
                Spc29Structure1(app)
            else
                Spc29Structure2(app)
            end
            app.Spc29PointInfo.Color = repmat(convertCharsToStrings(app.Spc29ColorChannelDropDown.Value),1,size(app.Spc29PointInfo.CoordPoints,2));
        end
    
        function Spc29Structure1(app)
            app.Spc29PointInfo.CoordPoints = StructurePointsGenerate(app,1,app.Spc29NumberofTubulesSpinner.Value,"~",app.Spc29StructureDiameternmSpinner.Value,app.Spc29NumberofFluorophoreSpinner.Value,app.Spc29LengthnmSpinner.Value,app.Spc29TubuleDiameternmSpinner.Value,false);
            app.Spc29PointInfo.CoordPoints = app.Spc29PointInfo.CoordPoints - [app.Spc29DistancetonmSpinner.Value;0;0];
        end
    
        function Spc29Structure2(app)
            Spc29Correction = (app.BioDistanceCorrection/norm(app.BioDistanceCorrection'))*(norm(app.BioDistanceCorrection') + app.Spc29DistancetonmSpinner.Value);
            app.Spc29PointInfo.CoordPoints = StructurePointsGenerate(app,1,app.Spc29NumberofTubulesSpinner.Value,"~",app.Spc29StructureDiameternmSpinner.Value,app.Spc29NumberofFluorophoreSpinner.Value,app.Spc29LengthnmSpinner.Value,app.Spc29TubuleDiameternmSpinner.Value,false);
            app.Spc29PointInfo.CoordPoints = (RotationMatrixGenerate(app,app.BioDistanceCorrection)*app.Spc29PointInfo.CoordPoints) - Spc29Correction;
            app.Spc29PointInfo.CoordPoints2 = StructurePointsGenerate(app,1+app.NumberofChromosomesSpinner.Value,app.Spc29NumberofTubulesSpinner.Value,"~",app.Spc29StructureDiameternmSpinner.Value,app.Spc29NumberofFluorophoreSpinner.Value,app.Spc29LengthnmSpinner.Value,app.Spc29TubuleDiameternmSpinner.Value,false);
            app.Spc29PointInfo.CoordPoints2 = (RotationMatrixGenerate(app,app.BioDistanceCorrection)*app.Spc29PointInfo.CoordPoints2) + Spc29Correction;
            app.Spc29PointInfo.CoordPoints = horzcat(app.Spc29PointInfo.CoordPoints, app.Spc29PointInfo.CoordPoints2);
        end

        function UpdatePlot(app)
            if isempty(app.MasterPointInfo.CoordPoints) == 0
                cmap = GenerateColorMap(app);
                scatter(app.UIAxes1,app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(2,:),[],cmap,'filled')
                scatter(app.UIAxes2,app.MasterPointInfo.CoordPoints(2,:),app.MasterPointInfo.CoordPoints(3,:),[],cmap,'filled')
                scatter(app.UIAxes3,app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(3,:),[],cmap,'filled')
                if app.AutomaticButton.Value == true
                    InitiateAutoLim(app)
                    PushAxisLimits(app)
                else
                    PullAxisLimits(app)
                end
            else
                cla(app.UIAxes1)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
            end
        end
    
        function InitiateAutoLim(app)
            app.UIAxes1.XLimMode = 'auto';
            app.UIAxes1.YLimMode = 'auto';
            app.UIAxes2.XLimMode = 'auto';
            app.UIAxes2.YLimMode = 'auto';
            app.UIAxes3.XLimMode = 'auto';
            app.UIAxes3.YLimMode = 'auto';
        end
        
        function PushAxisLimits(app)
            y1 = app.UIAxes1.YLim;
            x1 = app.UIAxes1.XLim;
            y2 = app.UIAxes2.YLim;
            x2 = app.UIAxes2.XLim;
            y3 = app.UIAxes3.YLim;
            x3 = app.UIAxes3.XLim;
            app.YLowerLimnmEditField1.Value = y1(1);
            app.YUpperLimnmEditField1.Value = y1(2);
            app.XLowerLimnmEditField1.Value = x1(1);
            app.XUpperLimnmEditField1.Value = x1(2)';
            app.YLowerLimnmEditField2.Value = y2(1);
            app.YUpperLimnmEditField2.Value = y2(2);
            app.XLowerLimnmEditField2.Value = x2(1);
            app.XUpperLimnmEditField2.Value = x2(2);
            app.YLowerLimnmEditField3.Value = y3(1);
            app.YUpperLimnmEditField3.Value = y3(2);
            app.XLowerLimnmEditField3.Value = x3(1);
            app.XUpperLimnmEditField3.Value = x3(2);
        end
    
        function PullAxisLimits(app)
            app.UIAxes1.YLim = [app.YLowerLimnmEditField1.Value, app.YUpperLimnmEditField1.Value];
            app.UIAxes1.XLim = [app.XLowerLimnmEditField1.Value, app.XUpperLimnmEditField1.Value];
            app.UIAxes2.YLim = [app.YLowerLimnmEditField2.Value, app.YUpperLimnmEditField2.Value];
            app.UIAxes2.XLim = [app.XLowerLimnmEditField2.Value, app.XUpperLimnmEditField2.Value];
            app.UIAxes3.YLim = [app.YLowerLimnmEditField3.Value, app.YUpperLimnmEditField3.Value];
            app.UIAxes3.XLim = [app.XLowerLimnmEditField3.Value, app.XUpperLimnmEditField3.Value];           
        end
        
        function SaveMasterPointInfo(app)
            MasterPointInfo = app.MasterPointInfo;
            save(strcat(app.FileFolderNameStringEditField.Value,'.mat'),'MasterPointInfo');
        end
    
        function GenerateXML(app)
            xmlstr = sprintf('<?xml version="1.0" encoding="ISO-8859-1"?><SimulatedExperiments file="C:/Users/ayush/Desktop/two_points.xml" modified="Mon Jul 23 13:30:09 2018&#10;" created="Mon Jul 23 13:30:09 2018&#10;"><Version major="2" minor="3" revision="0"/><AFMSimulation pixelSize="10.000000" imageWidth="300" imageHeight="300" clipGroundPlane="false" displayAsWireframe="false" surfaceOpacity="1.000000"/><FluorescenceSimulation focalPlaneIndex="14" focalPlaneSpacing="%.6f" numberOfFocalPlanes="%d" useCustomFocalPlanePositions="false" gain="%.6f" offset="%.6f" maximumVoxelIntensity="%.6f" pixelSize="%.6f" psfName="Gibson-Lanni Widefield" imageWidth="%d" imageHeight="%d" shearInX="0.000000" shearInY="0.000000" addGaussianNoise="true" noiseStdDev="%.6f" showImageVolumeOutline="false" showRefGrid="true" refGridSpacing="1000.000000" superimposeSimulatedImage="true" superimposeComparisonImage="false" minimumIntensityLevel="0.000000" maximumIntensityLevel="200.000015"><FocalPlanes>', app.SpacingBetweenPlanesnmSpinner.Value,app.NumberofPlanesSpinner.Value, app.GainEditField.Value,app.OffsetEditField.Value,app.MaxVoxelIntensityEditField.Value,app.PixelSizenmSpinner.Value,app.WidthpixelsSpinner.Value,app.HeightpixelsSpinner.Value,app.GaussianNoiseSDEditField.Value);
            for planeidx = 0:app.NumberofPlanesSpinner.Value
                xmlstr = strcat(xmlstr, sprintf('<Plane index="%d" position="0.000000"/>',planeidx));
            end
            xmlstr = strcat(xmlstr, '</FocalPlanes><GradientDescentFluorescenceOptimizer><Iterations value="100"/><DerivativeEstimateStepSize value="1e-008"/><StepScaleFactor value="1"/><ObjectiveFunction name="Gaussian Noise Maximum Likelihood"/></GradientDescentFluorescenceOptimizer><NelderMeadFluorescenceOptimizer><MaximumIterations value="100"/><ParametersConvergenceTolerance value="1e-008"/><ObjectiveFunction name="Gaussian Noise Maximum Likelihood"/></NelderMeadFluorescenceOptimizer><PointsGradientFluorescenceOptimizer><StepSize value="1"/><Iterations value="100"/><ObjectiveFunction name=""/></PointsGradientFluorescenceOptimizer><FluorescenceComparisonImageModelObject name="None"/></FluorescenceSimulation><ModelObjectList>');
            for pointidx = 1:size(app.MasterPointInfo.CoordPointsCorrect,2)
                if app.MasterPointInfo.Color(pointidx) == "Green"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="green" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPointsCorrect(1, pointidx), app.MasterPointInfo.CoordPointsCorrect(2, pointidx), app.MasterPointInfo.CoordPointsCorrect(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "Blue"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="blue" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPointsCorrect(1, pointidx), app.MasterPointInfo.CoordPointsCorrect(2, pointidx), app.MasterPointInfo.CoordPointsCorrect(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "Red"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="red" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPointsCorrect(1, pointidx), app.MasterPointInfo.CoordPointsCorrect(2, pointidx), app.MasterPointInfo.CoordPointsCorrect(3, pointidx)));
                elseif app.MasterPointInfo.Color(pointidx) == "All"
                    xmlstr = strcat(xmlstr, sprintf('<PointSetModel><Name value="Fluorophore"/><Visible value="true"/><PositionX value="%.6f" optimize="false"/><PositionY value="%.6f" optimize="false"/><PositionZ value="%.6f" optimize="false"/><RotationAngle value="0.000000" optimize="false"/><RotationVectorX value="1.000000" optimize="false"/><RotationVectorY value="0.000000" optimize="false"/><RotationVectorZ value="0.000000" optimize="false"/><VisibleRadius value="5.000000"/><NumberofPoints value="1"/><VerticesFluorophoreModel enabled="true" channel="all" intensityScale="1.000000" optimize="false"/><X1 value="0.000000" optimize="false"/><Y1 value="0.000000" optimize="false"/><Z1 value="0.000000" optimize="false"/></PointSetModel>', app.MasterPointInfo.CoordPointsCorrect(1, pointidx), app.MasterPointInfo.CoordPointsCorrect(2, pointidx), app.MasterPointInfo.CoordPointsCorrect(3, pointidx)));
                end
            end
            xmlstr = strcat(xmlstr, '</ModelObjectList></SimulatedExperiments>');
            xmlfile = fopen(strcat(app.FileFolderNameStringEditField.Value,'.xml'),'w');
            fprintf(xmlfile,xmlstr);
            fclose all;
        end
    
        function GenerateTIF(app)
            commandstring = 'python.exe BrownianXMLtoTIFF.py ';
            basename = app.FileFolderNameStringEditField.Value;
            if any(strcmp("All",app.MasterPointInfo.Color))
                commandstring = strcat(commandstring,{' '},'-out %s %s');
            else
                if any(strcmp("Green",app.MasterPointInfo.Color))
                    commandstring = strcat(commandstring,{' '}, '-green ');
                end
                if any(strcmp("Red",app.MasterPointInfo.Color))
                    commandstring = strcat(commandstring,{' '}, '-red ');
                end
                if any(strcmp("Blue",app.MasterPointInfo.Color))
                    commandstring = strcat(commandstring,{' '}, '-blue ');
                end
                commandstring = strcat(commandstring,{' '}, '-out %s %s');
            end
            system(sprintf(commandstring{1}, strcat(basename,'_TIF'), strcat(basename,'_DATA')))
        end
    
        function StructurePoints = StructurePointsGenerate(app,Index,NumberCylinders,Stagger,StructureDiameter,NumberofFluorophore,Length,TubuleDiameter,RotationCheck)
            StructurePoints = [];
            CoordAdjustment = zeros(3,1);
                for angle = 0:(2*pi/NumberCylinders):(2*pi-2*pi/NumberCylinders)
                    if strcmp(Stagger,'Random') || (strcmp(Stagger,'SimuLink Data (Stevens et al.)') && NumberCylinders <= 32)
                        CoordAdjustment(1,1) = app.Stagger(Index);
                    end
                    CoordAdjustment(2,1) = cos(angle)*StructureDiameter/2;
                    CoordAdjustment(3,1) = sin(angle)*StructureDiameter/2;
                    CylinderCoordPoints = CylinderFluorophorePoints(app, Index, NumberofFluorophore,Length,TubuleDiameter,RotationCheck);
                    StructurePoints = horzcat(StructurePoints, (CylinderCoordPoints + CoordAdjustment));
                    Index = Index + 1;
                end
        end
    
        function CylinderCoordPoints = CylinderFluorophorePoints(app,Index, NumberofFluorophore,Length,TubuleDiameter,RotationCheck)
                CylinderCoordPoints = zeros(3,NumberofFluorophore);
                for PointIdx = 1:NumberofFluorophore
                    CylinderCoordPoints(1,PointIdx) = -Length*rand();
                    CoordAng = rand()*2*pi;
                    CylinderCoordPoints(2,PointIdx) = cos(CoordAng)*TubuleDiameter/2;
                    CylinderCoordPoints(3,PointIdx) = sin(CoordAng)*TubuleDiameter/2;
                end
                if RotationCheck % Allow for rotation of the initial coordinates
                    CylinderCoordPoints = rotx(app.Rotation(Index,1))*roty(app.Rotation(Index,2))*rotz(app.Rotation(Index,3))*CylinderCoordPoints;
                end
        end
    
        function KinetochorePoints = KinetochoreGenerate(app,Index,NumberCylinders,Stagger,StructureDiameter,NumberofKComplexes,DistancetoTaper,RadialDisp,NumberofBound,MicrotubuleDiameter,TagPercent,UnboundLength,UpperAngle,LowerAngle)
            KinetochorePoints = [];
            CoordAdjustment = zeros(3,1);
                for angle = 0:(2*pi/NumberCylinders):(2*pi-2*pi/NumberCylinders)
                    if strcmp(Stagger,'Random') || (strcmp(Stagger,'SimuLink Data (Stevens et al.)') && NumberCylinders <= 32)
                        CoordAdjustment(1,1) = app.Stagger(Index);
                    end
                    CoordAdjustment(2,1) = cos(angle)*StructureDiameter/2;
                    CoordAdjustment(3,1) = sin(angle)*StructureDiameter/2;
                    KCPoints = KCFluorophorePoints(app,angle,NumberofKComplexes,DistancetoTaper,RadialDisp,NumberofBound,MicrotubuleDiameter,TagPercent,UnboundLength,UpperAngle,LowerAngle);
                    KinetochorePoints = horzcat(KinetochorePoints, (KCPoints + CoordAdjustment));
                    Index = Index + 1;
                end
        end
    
        function KCCoordPoints = KCFluorophorePoints(app,mainangle,NumberofKComplexes,DistancetoTaper,RadialDisp,NumberofBound,MicrotubuleDiameter,TagPercent,UnboundLength,UpperAngle,LowerAngle)
            BindingPoint = [DistancetoTaper;cos(mainangle)*RadialDisp;sin(mainangle)*RadialDisp];
            KCCoordPoints = zeros(3,NumberofKComplexes);
            KCBoolean = zeros(1,NumberofKComplexes);
            KCBoolean(randsample(length(KCBoolean),NumberofBound)) = 1;
            angles = 0:(2*pi/NumberofKComplexes):(2*pi-2*pi/NumberofKComplexes);
            for KCIndex = 1:NumberofKComplexes
                if KCBoolean(KCIndex) == 1
                    N2End = [0;cos(angles(KCIndex))*MicrotubuleDiameter/2;sin(angles(KCIndex))*MicrotubuleDiameter/2];
                    Difference = BindingPoint - N2End;
                    Point = Difference*TagPercent/100 + N2End;
                    KCCoordPoints(:,KCIndex) = Point;
                else
                    N2End = [0;cos(angles(KCIndex))*MicrotubuleDiameter/2;sin(angles(KCIndex))*MicrotubuleDiameter/2];
                    anglediff = UpperAngle - LowerAngle;
                    randangle = rand()*anglediff + LowerAngle;
                    Point = [cosd(randangle)*UnboundLength;sind(randangle)*UnboundLength;0]*TagPercent/100;
                    KCCoordPoints(:,KCIndex) = rotx(rad2deg(angles(KCIndex)))*Point+N2End;
                end
            end
        end
            
        
        function rotation = RotationMatrixGenerate(app,vector)
            ninit = [1;0;0]; 
            ninit = ninit/norm(ninit);
            nfinal = vector; 
            nfinal = nfinal/norm(nfinal); 
            c = dot(ninit,nfinal) / ( norm(ninit)*norm(nfinal) ); 
            s = sqrt(1-c*c);
            u = cross(ninit,nfinal) / ( norm(ninit)*norm(nfinal) );
            u = u/norm(u);
            C = 1-c;
            rotation = [u(1)^2*C+c, u(1)*u(2)*C-u(3)*s, u(1)*u(3)*C+u(2)*s
                u(2)*u(1)*C+u(3)*s, u(2)^2*C+c, u(2)*u(3)*C-u(1)*s
                u(3)*u(1)*C-u(2)*s, u(3)*u(2)*C+u(1)*s, u(3)^2*C+c];
        end
        
        function TestOrCreateDirectory(app)
            if 7 ~= exist(app.FileFolderNameStringEditField.Value,'dir')
                mkdir(app.FileFolderNameStringEditField.Value)
            end
        end
        function MasterPointInforCorrection(app)
            app.MasterPointInfo.CoordPointsCorrect = app.MasterPointInfo.CoordPoints + [(app.WidthpixelsSpinner.Value*app.PixelSizenmSpinner.Value)/2;(app.HeightpixelsSpinner.Value*app.PixelSizenmSpinner.Value)/2;((app.NumberofPlanesSpinner.Value-1)/2)*app.SpacingBetweenPlanesnmSpinner.Value];
        end
    
        function cmap = GenerateColorMap(app)
            cmap = [];
            for coloridx = 1:size(app.MasterPointInfo.Color,2)
               if app.MasterPointInfo.Color(coloridx) == "Red"
                   cmap(end+1,:) = [1 0 0];
               end
               if app.MasterPointInfo.Color(coloridx) == "Green"
                   cmap(end+1,:) = [0 1 0];
               end
               if app.MasterPointInfo.Color(coloridx) == "Blue"
                   cmap(end+1,:) = [0 0 1];
               end
               if app.MasterPointInfo.Color(coloridx) == "All"
                   cmap(end+1,:) = [0 0 0];
               end
            end
        end
        function PreProcess(app)
            SimulatedImagesPreProcess
        end
    
        function CopyBtoF(app)
            copyfile('BrownianXMLtoTIFF.py',app.FileFolderNameStringEditField.Value)
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GeneralParametreCheck(app)
            SaveBioDistanceData(app)
            GenerateSimuLinkData(app)
            UpdateData(app)
            UpdatePlot(app)
        end

        % Callback function: LowLimSpinner, 
        % MicrotubuleDiameternmSpinner, NumberofChromosomesSpinner, 
        % NumberofComplexesSwitch, RangenmSpinner, RotationCheckBox, 
        % RotationParametersPanel, StaggerDropDown, 
        % StructureDiameternmSpinner, UpLimSpinner, XSpinner, YSpinner, 
        % ZSpinner
        function MainParameterChange(app, event)
            MainParameterCheck(app)
            UpdateData(app)
            UpdatePlot(app)
        end

        % Callback function: FileFolderNameStringEditField, 
        % GenerateTIFsCheckBox, GenerateXMLCheckBox, SaveMATFileCheckBox
        function OutputParameterChange(app, event)
            OutputParameterLocationCheck(app)
            OutputParameterCheck(app)
        end

        % Button pushed function: GenerateOutputButton
        function GenerateOutputButtonPushed(app, event)
            if isempty(app.MasterPointInfo.CoordPoints) == 1
                app.StatusEditField.Value = 'No Structure Has Been Selected';
            else
                app.StatusEditField.Value = 'Saving/Writing/Taking Pictures. Please Hold.';
                app.FileFolderNameStringEditField.Value = strtrim(app.FileFolderNameStringEditField.Value);
                TestOrCreateDirectory(app)
                CopyBtoF(app)
                cd(app.FileFolderNameStringEditField.Value)
                mkdir(strcat(app.FileFolderNameStringEditField.Value,'_DATA'))
                cd(strcat(app.FileFolderNameStringEditField.Value,'_DATA'))
                if app.SaveMATFileCheckBox.Value == true
                    SaveMasterPointInfo(app);
                end
                MasterPointInforCorrection(app)
                if app.GenerateXMLCheckBox.Value == true
                    GenerateXML(app);
                end
                cd ..
                if strcmp(app.MicroscopeSimulatorParametersPanel.Visible,'on') && app.GenerateTIFsCheckBox.Value == true
                    GenerateTIF(app)
                end
                if strcmp(app.MicroscopeSimulatorParametersPanel.Visible,'on') && app.GenerateTIFsCheckBox.Value == true && app.PreProcessTIFsCheckBox.Value == true
                    PreProcess(app)
                end
                app.StatusEditField.Value = 'Finished!';
            end
        end

        % Button pushed function: DViewPopOutButton
        function DViewPopOutButtonPushed(app, event)
            if ~isempty(app.MasterPointInfo.CoordPoints) == 1
                cmap = GenerateColorMap(app);
                figure()
                scatter3(app.MasterPointInfo.CoordPoints(1,:),app.MasterPointInfo.CoordPoints(2,:),app.MasterPointInfo.CoordPoints(3,:),[],cmap,'filled')
                rotate3d on
            end
        end

        % Selection changed function: AxisLimitsButtonGroup
        function AxisLimitsParameterChange(app, event)
            AxisParameterCheck(app)
            UpdatePlot(app)
        end

        % Value changed function: XLowerLimnmEditField1, 
        % XLowerLimnmEditField2, XLowerLimnmEditField3, 
        % XUpperLimnmEditField1, XUpperLimnmEditField2, 
        % XUpperLimnmEditField3, YLowerLimnmEditField1, 
        % YLowerLimnmEditField2, YLowerLimnmEditField3, 
        % YUpperLimnmEditField1, YUpperLimnmEditField2, 
        % YUpperLimnmEditField3
        function AxisLimitsValueChange(app, event)
            UpdatePlot(app)
        end

        % Menu selected function: GitHubMenu
        function GitHubMenuSelected(app, event)
            web('https://github.com/BloomLabYeast','-browser');
        end

        % Menu selected function: OtherProgramsMenu
        function OtherProgramsMenuSelected(app, event)
            web('http://bloomlab.web.unc.edu/resources/programs/','-browser');
        end

        % Menu selected function: BloomLabWebsiteMenu
        function BloomLabWebsiteMenuSelected(app, event)
            web('http://bloomlab.web.unc.edu/','-browser')
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            delete(app)
        end

        % Callback function: N_Nuf2CheckBox, 
        % N_Nuf2ColorChannelDropDown, N_Nuf2LengthnmSpinner, 
        % N_Nuf2NumberofFluorophoreSpinner
        function N_Nuf2StructureValueChange(app, event)
            UpdateData(app)
            UpdatePlot(app)
        end

        % Callback function: Spc29CheckBox, 
        % Spc29ColorChannelDropDown, Spc29DistancetonmSpinner, 
        % Spc29LengthnmSpinner, Spc29NumberofFluorophoreSpinner, 
        % Spc29NumberofTubulesSpinner, Spc29StructureDiameternmSpinner, 
        % Spc29TubuleDiameternmSpinner
        function Spc29StructureValueChange(app, event)
            UpdateData(app)
            UpdatePlot(app)
        end

        % Callback function: DistanceofTapernmSpinner, 
        % KinetochoreCheckBox, KinetochoreColorChannel, 
        % LengthofUnboundnmSpinner, LowerAngleSpinner, 
        % NumberofBoundComplexesSpinner, NumberofComplexesSpinner, 
        % RadialDispofTapernmSpinner, UpperAngleSpinner, 
        % ofLengthMarkedSpinner
        function KinetochoreStructureValueChange(app, event)
            KinetochoreValueChangeCheck(app)
            UpdateData(app)
            UpdatePlot(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StaticKinetUIFigure = uifigure;
            app.StaticKinetUIFigure.Name = 'Static Kinet';
            app.FileMenu = uimenu(app.StaticKinetUIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.AboutMenu = uimenu(app.StaticKinetUIFigure);
            app.AboutMenu.Text = 'About';
            app.BloomLabWebsiteMenu = uimenu(app.AboutMenu);
            app.BloomLabWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @BloomLabWebsiteMenuSelected, true);
            app.BloomLabWebsiteMenu.Separator = 'on';
            app.BloomLabWebsiteMenu.Text = 'Bloom Lab Website';
            app.OtherProgramsMenu = uimenu(app.AboutMenu);
            app.OtherProgramsMenu.MenuSelectedFcn = createCallbackFcn(app, @OtherProgramsMenuSelected, true);
            app.OtherProgramsMenu.Separator = 'on';
            app.OtherProgramsMenu.Text = 'Other Programs';
            app.GitHubMenu = uimenu(app.AboutMenu);
            app.GitHubMenu.MenuSelectedFcn = createCallbackFcn(app, @GitHubMenuSelected, true);
            app.GitHubMenu.Separator = 'on';
            app.GitHubMenu.Text = 'GitHub';
            app.HelpMenu = uimenu(app.StaticKinetUIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Separator = 'on';
            app.DocumentationMenu.Text = 'Documentation';
            app.N_Nuf2StructurePanel = uipanel(app.StaticKinetUIFigure);
            app.N_Nuf2StructurePanel.Title = 'N-Terminal Nuf2';
            app.N_Nuf2NumberofFluorophoreSpinnerLabel = uilabel(app.N_Nuf2StructurePanel);
            app.N_Nuf2NumberofFluorophoreSpinnerLabel.Text = 'Number of Fluorophore';
            app.N_Nuf2LengthnmSpinnerLabel = uilabel(app.N_Nuf2StructurePanel);
            app.N_Nuf2LengthnmSpinnerLabel.Text = 'Length (nm)';
            app.N_Nuf2LengthnmSpinner = uispinner(app.N_Nuf2StructurePanel);
            app.N_Nuf2LengthnmSpinner.LowerLimitInclusive = 'off';
            app.N_Nuf2LengthnmSpinner.UpperLimitInclusive = 'off';
            app.N_Nuf2LengthnmSpinner.ValueChangingFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2LengthnmSpinner.Limits = [0 Inf];
            app.N_Nuf2LengthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2LengthnmSpinner.Value = 2;
            app.N_Nuf2NumberofFluorophoreSpinner = uispinner(app.N_Nuf2StructurePanel);
            app.N_Nuf2NumberofFluorophoreSpinner.LowerLimitInclusive = 'off';
            app.N_Nuf2NumberofFluorophoreSpinner.UpperLimitInclusive = 'off';
            app.N_Nuf2NumberofFluorophoreSpinner.ValueChangingFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2NumberofFluorophoreSpinner.Limits = [0 Inf];
            app.N_Nuf2NumberofFluorophoreSpinner.RoundFractionalValues = 'on';
            app.N_Nuf2NumberofFluorophoreSpinner.ValueChangedFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2NumberofFluorophoreSpinner.Value = 20;
            app.N_Nuf2ColorChannelDropDownLabel = uilabel(app.N_Nuf2StructurePanel);
            app.N_Nuf2ColorChannelDropDownLabel.Text = 'Color Channel';
            app.N_Nuf2ColorChannelDropDown = uidropdown(app.N_Nuf2StructurePanel);
            app.N_Nuf2ColorChannelDropDown.Items = {'All', 'Green', 'Red', 'Blue'};
            app.N_Nuf2ColorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2ColorChannelDropDown.Value = 'All';
            app.OutputPanel = uipanel(app.StaticKinetUIFigure);
            app.OutputPanel.Title = 'Output';
            app.FileFolderNameStringLabel = uilabel(app.OutputPanel);
            app.FileFolderNameStringLabel.Text = 'File/Folder Name String:';
            app.FileFolderNameStringEditField = uieditfield(app.OutputPanel, 'text');
            app.FileFolderNameStringEditField.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.FileFolderNameStringEditField.ValueChangingFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateXMLCheckBox = uicheckbox(app.OutputPanel);
            app.GenerateXMLCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateXMLCheckBox.Text = 'Generate XML';
            app.SaveMATFileCheckBox = uicheckbox(app.OutputPanel);
            app.SaveMATFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.SaveMATFileCheckBox.Text = 'Save MAT File';
            app.MicroscopeSimulatorParametersPanel = uipanel(app.OutputPanel);
            app.MicroscopeSimulatorParametersPanel.Title = 'Microscope Simulator Parameters';
            app.MicroscopeSimulatorParametersPanel.Visible = 'off';
            app.GainLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.GainLabel.Text = 'Gain:';
            app.GainEditField = uieditfield(app.MicroscopeSimulatorParametersPanel, 'numeric');
            app.GainEditField.UpperLimitInclusive = 'off';
            app.GainEditField.Limits = [0 Inf];
            app.GainEditField.Value = 76660;
            app.OffsetLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.OffsetLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.MicroscopeSimulatorParametersPanel, 'numeric');
            app.OffsetEditField.UpperLimitInclusive = 'off';
            app.OffsetEditField.Limits = [0 Inf];
            app.GaussianNoiseSDLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.GaussianNoiseSDLabel.Text = 'Gaussian Noise SD:';
            app.GaussianNoiseSDEditField = uieditfield(app.MicroscopeSimulatorParametersPanel, 'numeric');
            app.GaussianNoiseSDEditField.UpperLimitInclusive = 'off';
            app.GaussianNoiseSDEditField.Limits = [0 Inf];
            app.MaxVoxelIntensityLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.MaxVoxelIntensityLabel.Text = 'Max. Voxel Intensity:';
            app.MaxVoxelIntensityEditField = uieditfield(app.MicroscopeSimulatorParametersPanel, 'numeric');
            app.MaxVoxelIntensityEditField.UpperLimitInclusive = 'off';
            app.MaxVoxelIntensityEditField.Limits = [0 Inf];
            app.MaxVoxelIntensityEditField.Value = 200;
            app.GenerateTIFsCheckBox = uicheckbox(app.MicroscopeSimulatorParametersPanel);
            app.GenerateTIFsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputParameterChange, true);
            app.GenerateTIFsCheckBox.Text = 'Generate TIFs';
            app.NumberofPlanesSpinnerLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.NumberofPlanesSpinnerLabel.Text = 'Number of Planes';
            app.NumberofPlanesSpinner = uispinner(app.MicroscopeSimulatorParametersPanel);
            app.NumberofPlanesSpinner.LowerLimitInclusive = 'off';
            app.NumberofPlanesSpinner.UpperLimitInclusive = 'off';
            app.NumberofPlanesSpinner.Limits = [0 Inf];
            app.NumberofPlanesSpinner.Value = 7;
            app.SpacingBetweenPlanesnmSpinnerLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.SpacingBetweenPlanesnmSpinnerLabel.Text = 'Spacing Between Planes (nm)';
            app.SpacingBetweenPlanesnmSpinner = uispinner(app.MicroscopeSimulatorParametersPanel);
            app.SpacingBetweenPlanesnmSpinner.Step = 50;
            app.SpacingBetweenPlanesnmSpinner.LowerLimitInclusive = 'off';
            app.SpacingBetweenPlanesnmSpinner.UpperLimitInclusive = 'off';
            app.SpacingBetweenPlanesnmSpinner.Limits = [0 Inf];
            app.SpacingBetweenPlanesnmSpinner.Value = 300;
            app.WidthpixelsSpinnerLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.WidthpixelsSpinnerLabel.Text = 'Width (pixels)';
            app.WidthpixelsSpinner = uispinner(app.MicroscopeSimulatorParametersPanel);
            app.WidthpixelsSpinner.Step = 10;
            app.WidthpixelsSpinner.UpperLimitInclusive = 'off';
            app.WidthpixelsSpinner.Limits = [1 Inf];
            app.WidthpixelsSpinner.ValueDisplayFormat = '%.0f';
            app.WidthpixelsSpinner.Value = 40;
            app.HeightpixelsSpinnerLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.HeightpixelsSpinnerLabel.Text = 'Height (pixels)';
            app.HeightpixelsSpinner = uispinner(app.MicroscopeSimulatorParametersPanel);
            app.HeightpixelsSpinner.Step = 10;
            app.HeightpixelsSpinner.UpperLimitInclusive = 'off';
            app.HeightpixelsSpinner.Limits = [1 Inf];
            app.HeightpixelsSpinner.ValueDisplayFormat = '%.0f';
            app.HeightpixelsSpinner.Value = 40;
            app.PixelSizenmSpinnerLabel = uilabel(app.MicroscopeSimulatorParametersPanel);
            app.PixelSizenmSpinnerLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmSpinner = uispinner(app.MicroscopeSimulatorParametersPanel);
            app.PixelSizenmSpinner.LowerLimitInclusive = 'off';
            app.PixelSizenmSpinner.UpperLimitInclusive = 'off';
            app.PixelSizenmSpinner.Limits = [0 Inf];
            app.PixelSizenmSpinner.Value = 65;
            app.PreProcessTIFsCheckBox = uicheckbox(app.MicroscopeSimulatorParametersPanel);
            app.PreProcessTIFsCheckBox.Text = 'Pre-Process TIF''s';
            app.StatusOutputPanel = uipanel(app.OutputPanel);
            app.GenerateOutputButton = uibutton(app.StatusOutputPanel, 'push');
            app.GenerateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOutputButtonPushed, true);
            app.GenerateOutputButton.Enable = 'off';
            app.GenerateOutputButton.Text = 'Generate Output!';
            app.StatusLabel = uilabel(app.StatusOutputPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.StatusOutputPanel, 'text');
            app.UIAxes2 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes2, '+Y vs. +Z')
            app.UIAxes1 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes1, '+X vs. +Y')
            app.UIAxes3 = uiaxes(app.StaticKinetUIFigure);
            title(app.UIAxes3, '+X vs. +Z')
            app.AxisLimPanel = uipanel(app.StaticKinetUIFigure);
            app.AxisLimPanel.Visible = 'off';
            app.YLowerLimnmEditFieldLabel = uilabel(app.AxisLimPanel);
            app.YLowerLimnmEditFieldLabel.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField1 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YLowerLimnmEditField1.LowerLimitInclusive = 'off';
            app.YLowerLimnmEditField1.UpperLimitInclusive = 'off';
            app.YLowerLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditFieldLabel = uilabel(app.AxisLimPanel);
            app.YUpperLimnmEditFieldLabel.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField1 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YUpperLimnmEditField1.LowerLimitInclusive = 'off';
            app.YUpperLimnmEditField1.UpperLimitInclusive = 'off';
            app.YUpperLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditFieldLabel = uilabel(app.AxisLimPanel);
            app.XLowerLimnmEditFieldLabel.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField1 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XLowerLimnmEditField1.LowerLimitInclusive = 'off';
            app.XLowerLimnmEditField1.UpperLimitInclusive = 'off';
            app.XLowerLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditFieldLabel = uilabel(app.AxisLimPanel);
            app.XUpperLimnmEditFieldLabel.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField1 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XUpperLimnmEditField1.LowerLimitInclusive = 'off';
            app.XUpperLimnmEditField1.UpperLimitInclusive = 'off';
            app.XUpperLimnmEditField1.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YLowerLimnmEditField_2Label = uilabel(app.AxisLimPanel);
            app.YLowerLimnmEditField_2Label.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField2 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YLowerLimnmEditField2.LowerLimitInclusive = 'off';
            app.YLowerLimnmEditField2.UpperLimitInclusive = 'off';
            app.YLowerLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditField_2Label = uilabel(app.AxisLimPanel);
            app.YUpperLimnmEditField_2Label.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField2 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YUpperLimnmEditField2.LowerLimitInclusive = 'off';
            app.YUpperLimnmEditField2.UpperLimitInclusive = 'off';
            app.YUpperLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditField_2Label = uilabel(app.AxisLimPanel);
            app.XLowerLimnmEditField_2Label.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField2 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XLowerLimnmEditField2.LowerLimitInclusive = 'off';
            app.XLowerLimnmEditField2.UpperLimitInclusive = 'off';
            app.XLowerLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditField_2Label = uilabel(app.AxisLimPanel);
            app.XUpperLimnmEditField_2Label.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField2 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XUpperLimnmEditField2.LowerLimitInclusive = 'off';
            app.XUpperLimnmEditField2.UpperLimitInclusive = 'off';
            app.XUpperLimnmEditField2.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YLowerLimnmEditField_3Label = uilabel(app.AxisLimPanel);
            app.YLowerLimnmEditField_3Label.Text = 'Y Lower Lim. (nm)';
            app.YLowerLimnmEditField3 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YLowerLimnmEditField3.LowerLimitInclusive = 'off';
            app.YLowerLimnmEditField3.UpperLimitInclusive = 'off';
            app.YLowerLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.YUpperLimnmEditField_3Label = uilabel(app.AxisLimPanel);
            app.YUpperLimnmEditField_3Label.Text = 'Y Upper Lim. (nm)';
            app.YUpperLimnmEditField3 = uieditfield(app.AxisLimPanel, 'numeric');
            app.YUpperLimnmEditField3.LowerLimitInclusive = 'off';
            app.YUpperLimnmEditField3.UpperLimitInclusive = 'off';
            app.YUpperLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XLowerLimnmEditField_3Label = uilabel(app.AxisLimPanel);
            app.XLowerLimnmEditField_3Label.Text = 'X Lower Lim. (nm)';
            app.XLowerLimnmEditField3 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XLowerLimnmEditField3.LowerLimitInclusive = 'off';
            app.XLowerLimnmEditField3.UpperLimitInclusive = 'off';
            app.XLowerLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.XUpperLimnmEditField_3Label = uilabel(app.AxisLimPanel);
            app.XUpperLimnmEditField_3Label.Text = 'X Upper Lim. (nm)';
            app.XUpperLimnmEditField3 = uieditfield(app.AxisLimPanel, 'numeric');
            app.XUpperLimnmEditField3.LowerLimitInclusive = 'off';
            app.XUpperLimnmEditField3.UpperLimitInclusive = 'off';
            app.XUpperLimnmEditField3.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsValueChange, true);
            app.GraphPanel = uipanel(app.StaticKinetUIFigure);
            app.DViewPopOutButton = uibutton(app.GraphPanel, 'push');
            app.DViewPopOutButton.ButtonPushedFcn = createCallbackFcn(app, @DViewPopOutButtonPushed, true);
            app.DViewPopOutButton.Text = '3D View (Pop-Out)';
            app.AxisLimitsButtonGroup = uibuttongroup(app.GraphPanel);
            app.AxisLimitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxisLimitsParameterChange, true);
            app.AxisLimitsButtonGroup.Title = 'Axis Limits';
            app.AutomaticButton = uiradiobutton(app.AxisLimitsButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.FixedButton = uiradiobutton(app.AxisLimitsButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.NumberofComplexesSwitchLabel = uilabel(app.StaticKinetUIFigure);
            app.NumberofComplexesSwitchLabel.Text = 'Number of Complexes';
            app.NumberofComplexesSwitch = uiswitch(app.StaticKinetUIFigure, 'slider');
            app.NumberofComplexesSwitch.Items = {'1', '2'};
            app.NumberofComplexesSwitch.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.NumberofComplexesSwitch.Value = '1';
            app.Spc29StructurePanel = uipanel(app.StaticKinetUIFigure);
            app.Spc29StructurePanel.Title = 'Spc-29';
            app.Spc29TubuleDiameternmSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29TubuleDiameternmSpinnerLabel.Text = 'Tubule Diameter (nm)';
            app.Spc29TubuleDiameternmSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29TubuleDiameternmSpinner.Step = 5;
            app.Spc29TubuleDiameternmSpinner.LowerLimitInclusive = 'off';
            app.Spc29TubuleDiameternmSpinner.UpperLimitInclusive = 'off';
            app.Spc29TubuleDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29TubuleDiameternmSpinner.Limits = [0 Inf];
            app.Spc29TubuleDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29TubuleDiameternmSpinner.Value = 25;
            app.Spc29StructureDiameternmSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29StructureDiameternmSpinnerLabel.Text = 'Structure Diameter (nm)';
            app.Spc29StructureDiameternmSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29StructureDiameternmSpinner.Step = 10;
            app.Spc29StructureDiameternmSpinner.LowerLimitInclusive = 'off';
            app.Spc29StructureDiameternmSpinner.UpperLimitInclusive = 'off';
            app.Spc29StructureDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29StructureDiameternmSpinner.Limits = [0 Inf];
            app.Spc29StructureDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29StructureDiameternmSpinner.Value = 100;
            app.Spc29NumberofFluorophoreSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29NumberofFluorophoreSpinnerLabel.Text = 'Number of Fluorophore';
            app.Spc29NumberofFluorophoreSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29NumberofFluorophoreSpinner.LowerLimitInclusive = 'off';
            app.Spc29NumberofFluorophoreSpinner.UpperLimitInclusive = 'off';
            app.Spc29NumberofFluorophoreSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29NumberofFluorophoreSpinner.Limits = [0 Inf];
            app.Spc29NumberofFluorophoreSpinner.RoundFractionalValues = 'on';
            app.Spc29NumberofFluorophoreSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29NumberofFluorophoreSpinner.Value = 20;
            app.Spc29ColorChannelDropDownLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29ColorChannelDropDownLabel.Text = 'Color Channel';
            app.Spc29ColorChannelDropDown = uidropdown(app.Spc29StructurePanel);
            app.Spc29ColorChannelDropDown.Items = {'All', 'Green', 'Red', 'Blue'};
            app.Spc29ColorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29ColorChannelDropDown.Value = 'All';
            app.Spc29NumberofTubulesSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29NumberofTubulesSpinnerLabel.Text = 'Number of Tubules';
            app.Spc29NumberofTubulesSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29NumberofTubulesSpinner.LowerLimitInclusive = 'off';
            app.Spc29NumberofTubulesSpinner.UpperLimitInclusive = 'off';
            app.Spc29NumberofTubulesSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29NumberofTubulesSpinner.Limits = [0 Inf];
            app.Spc29NumberofTubulesSpinner.RoundFractionalValues = 'on';
            app.Spc29NumberofTubulesSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29NumberofTubulesSpinner.Value = 16;
            app.Spc29LengthnmSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29LengthnmSpinnerLabel.Text = 'Length (nm)';
            app.Spc29LengthnmSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29LengthnmSpinner.LowerLimitInclusive = 'off';
            app.Spc29LengthnmSpinner.UpperLimitInclusive = 'off';
            app.Spc29LengthnmSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29LengthnmSpinner.Limits = [0 Inf];
            app.Spc29LengthnmSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29LengthnmSpinner.Value = 2;
            app.Spc29DistancetonmSpinnerLabel = uilabel(app.Spc29StructurePanel);
            app.Spc29DistancetonmSpinnerLabel.Text = 'Distance to (+) (nm)';
            app.Spc29DistancetonmSpinner = uispinner(app.Spc29StructurePanel);
            app.Spc29DistancetonmSpinner.Step = 10;
            app.Spc29DistancetonmSpinner.UpperLimitInclusive = 'off';
            app.Spc29DistancetonmSpinner.ValueChangingFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29DistancetonmSpinner.Limits = [0 Inf];
            app.Spc29DistancetonmSpinner.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29DistancetonmSpinner.Value = 350;
            app.MicrotubuleDiameternmLabel = uilabel(app.StaticKinetUIFigure);
            app.MicrotubuleDiameternmLabel.Text = 'Microtubule Diameter (nm)';
            app.MicrotubuleDiameternmSpinner = uispinner(app.StaticKinetUIFigure);
            app.MicrotubuleDiameternmSpinner.Step = 5;
            app.MicrotubuleDiameternmSpinner.LowerLimitInclusive = 'off';
            app.MicrotubuleDiameternmSpinner.UpperLimitInclusive = 'off';
            app.MicrotubuleDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.MicrotubuleDiameternmSpinner.Limits = [0 Inf];
            app.MicrotubuleDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.MicrotubuleDiameternmSpinner.Value = 25;
            app.StructureDiameternmSpinnerLabel = uilabel(app.StaticKinetUIFigure);
            app.StructureDiameternmSpinnerLabel.Text = 'Structure Diameter (nm)';
            app.StructureDiameternmSpinner = uispinner(app.StaticKinetUIFigure);
            app.StructureDiameternmSpinner.Step = 10;
            app.StructureDiameternmSpinner.LowerLimitInclusive = 'off';
            app.StructureDiameternmSpinner.UpperLimitInclusive = 'off';
            app.StructureDiameternmSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.StructureDiameternmSpinner.Limits = [0 Inf];
            app.StructureDiameternmSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.StructureDiameternmSpinner.Value = 250;
            app.NumberofChromosomesSpinnerLabel = uilabel(app.StaticKinetUIFigure);
            app.NumberofChromosomesSpinnerLabel.Text = 'Number of Chromosomes';
            app.NumberofChromosomesSpinner = uispinner(app.StaticKinetUIFigure);
            app.NumberofChromosomesSpinner.LowerLimitInclusive = 'off';
            app.NumberofChromosomesSpinner.UpperLimitInclusive = 'off';
            app.NumberofChromosomesSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.NumberofChromosomesSpinner.Limits = [0 Inf];
            app.NumberofChromosomesSpinner.RoundFractionalValues = 'on';
            app.NumberofChromosomesSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.NumberofChromosomesSpinner.Value = 16;
            app.StaggerDropDownLabel = uilabel(app.StaticKinetUIFigure);
            app.StaggerDropDownLabel.Text = 'Stagger';
            app.StaggerDropDown = uidropdown(app.StaticKinetUIFigure);
            app.StaggerDropDown.Items = {'None', 'Random', 'SimuLink Data (Stevens et al.)'};
            app.StaggerDropDown.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.StaggerDropDown.Value = 'None';
            app.RangenmSpinnerLabel = uilabel(app.StaticKinetUIFigure);
            app.RangenmSpinnerLabel.Visible = 'off';
            app.RangenmSpinnerLabel.Text = 'Range +/- (nm)';
            app.RangenmSpinner = uispinner(app.StaticKinetUIFigure);
            app.RangenmSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.RangenmSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.RangenmSpinner.Visible = 'off';
            app.N_Nuf2CheckBox = uicheckbox(app.StaticKinetUIFigure);
            app.N_Nuf2CheckBox.ValueChangedFcn = createCallbackFcn(app, @N_Nuf2StructureValueChange, true);
            app.N_Nuf2CheckBox.Text = '';
            app.Spc29CheckBox = uicheckbox(app.StaticKinetUIFigure);
            app.Spc29CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spc29StructureValueChange, true);
            app.Spc29CheckBox.Text = '';
            app.KinetochoreProteinPanel = uipanel(app.StaticKinetUIFigure);
            app.KinetochoreProteinPanel.Title = 'Kinetochore Protein';
            app.LengthofUnboundnmSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.LengthofUnboundnmSpinnerLabel.Text = 'Length of Unbound (nm)';
            app.LengthofUnboundnmSpinner = uispinner(app.KinetochoreProteinPanel);
            app.LengthofUnboundnmSpinner.Step = 10;
            app.LengthofUnboundnmSpinner.LowerLimitInclusive = 'off';
            app.LengthofUnboundnmSpinner.UpperLimitInclusive = 'off';
            app.LengthofUnboundnmSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.LengthofUnboundnmSpinner.Limits = [0 Inf];
            app.LengthofUnboundnmSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.LengthofUnboundnmSpinner.Value = 70;
            app.NumberofComplexesSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.NumberofComplexesSpinnerLabel.Text = 'Number of Complexes';
            app.NumberofComplexesSpinner = uispinner(app.KinetochoreProteinPanel);
            app.NumberofComplexesSpinner.UpperLimitInclusive = 'off';
            app.NumberofComplexesSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.NumberofComplexesSpinner.Limits = [0 Inf];
            app.NumberofComplexesSpinner.RoundFractionalValues = 'on';
            app.NumberofComplexesSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.NumberofComplexesSpinner.Value = 8;
            app.DistanceofTapernmSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.DistanceofTapernmSpinnerLabel.Text = 'Distance of Taper (nm)';
            app.DistanceofTapernmSpinner = uispinner(app.KinetochoreProteinPanel);
            app.DistanceofTapernmSpinner.LowerLimitInclusive = 'off';
            app.DistanceofTapernmSpinner.UpperLimitInclusive = 'off';
            app.DistanceofTapernmSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.DistanceofTapernmSpinner.Limits = [0 Inf];
            app.DistanceofTapernmSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.DistanceofTapernmSpinner.Value = 20;
            app.NumberofBoundComplexesSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.NumberofBoundComplexesSpinnerLabel.Text = 'Number of Bound Complexes';
            app.NumberofBoundComplexesSpinner = uispinner(app.KinetochoreProteinPanel);
            app.NumberofBoundComplexesSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.NumberofBoundComplexesSpinner.Limits = [0 Inf];
            app.NumberofBoundComplexesSpinner.RoundFractionalValues = 'on';
            app.NumberofBoundComplexesSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.NumberofBoundComplexesSpinner.Value = 8;
            app.RadialDispofTapernmSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.RadialDispofTapernmSpinnerLabel.Text = 'Radial Disp. of Taper (nm)';
            app.RadialDispofTapernmSpinner = uispinner(app.KinetochoreProteinPanel);
            app.RadialDispofTapernmSpinner.UpperLimitInclusive = 'off';
            app.RadialDispofTapernmSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.RadialDispofTapernmSpinner.Limits = [0 Inf];
            app.RadialDispofTapernmSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.AngleRangeforUnbounddegSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.AngleRangeforUnbounddegSpinnerLabel.Text = 'Angle Range for Unbound (deg.)';
            app.LowerAngleSpinner = uispinner(app.KinetochoreProteinPanel);
            app.LowerAngleSpinner.Step = 5;
            app.LowerAngleSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.LowerAngleSpinner.Limits = [-90 90];
            app.LowerAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.LowerAngleSpinner.Value = -90;
            app.UpperAngleLabel = uilabel(app.KinetochoreProteinPanel);
            app.UpperAngleLabel.Text = 'to';
            app.UpperAngleSpinner = uispinner(app.KinetochoreProteinPanel);
            app.UpperAngleSpinner.Step = 5;
            app.UpperAngleSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.UpperAngleSpinner.Limits = [-90 90];
            app.UpperAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.UpperAngleSpinner.Value = 90;
            app.ofLengthMarkedSpinnerLabel = uilabel(app.KinetochoreProteinPanel);
            app.ofLengthMarkedSpinnerLabel.Text = '% of Length Marked';
            app.ofLengthMarkedSpinner = uispinner(app.KinetochoreProteinPanel);
            app.ofLengthMarkedSpinner.LowerLimitInclusive = 'off';
            app.ofLengthMarkedSpinner.UpperLimitInclusive = 'off';
            app.ofLengthMarkedSpinner.ValueChangingFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.ofLengthMarkedSpinner.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.KinetochoreColorChannelLabel = uilabel(app.KinetochoreProteinPanel);
            app.KinetochoreColorChannelLabel.Text = 'Color Channel';
            app.KinetochoreColorChannel = uidropdown(app.KinetochoreProteinPanel);
            app.KinetochoreColorChannel.Items = {'All', 'Green', 'Red', 'Blue'};
            app.KinetochoreColorChannel.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.KinetochoreColorChannel.Value = 'All';
            app.KinetochoreCheckBox = uicheckbox(app.StaticKinetUIFigure);
            app.KinetochoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @KinetochoreStructureValueChange, true);
            app.KinetochoreCheckBox.Text = '';
            app.RotationPanel = uipanel(app.StaticKinetUIFigure);
            app.RotationParametersPanel = uibuttongroup(app.RotationPanel);
            app.RotationParametersPanel.SelectionChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.FixedRotationButton = uiradiobutton(app.RotationParametersPanel);
            app.FixedRotationButton.Text = 'Fixed Rotation';
            app.FixedRotationButton.Value = true;
            app.RandomRotationButton = uiradiobutton(app.RotationParametersPanel);
            app.RandomRotationButton.Text = 'Random Rotation';
            app.RotationCheckBox = uicheckbox(app.RotationPanel);
            app.RotationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.RotationCheckBox.Text = '';
            app.RotationLabel = uilabel(app.RotationPanel);
            app.RotationLabel.Text = 'Rotation';
            app.RandomRotationParametersdegPanel = uipanel(app.RotationPanel);
            app.RandomRotationParametersdegPanel.Title = 'Random Rotation Parameters (deg.)';
            app.LowLimSpinnerLabel = uilabel(app.RandomRotationParametersdegPanel);
            app.LowLimSpinnerLabel.Text = 'Low. Lim.';
            app.LowLimSpinner = uispinner(app.RandomRotationParametersdegPanel);
            app.LowLimSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.LowLimSpinner.Limits = [0 360];
            app.LowLimSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.UpLimSpinnerLabel = uilabel(app.RandomRotationParametersdegPanel);
            app.UpLimSpinnerLabel.Text = 'Up. Lim.';
            app.UpLimSpinner = uispinner(app.RandomRotationParametersdegPanel);
            app.UpLimSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.UpLimSpinner.Limits = [0 360];
            app.UpLimSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.FixedRotationParametersdegPanel = uipanel(app.RotationPanel);
            app.FixedRotationParametersdegPanel.Title = 'Fixed Rotation Parameters (deg.)';
            app.XSpinnerLabel = uilabel(app.FixedRotationParametersdegPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.FixedRotationParametersdegPanel);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.XSpinner.Limits = [0 360];
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.YSpinnerLabel = uilabel(app.FixedRotationParametersdegPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.FixedRotationParametersdegPanel);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.YSpinner.Limits = [0 360];
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.ZSpinnerLabel = uilabel(app.FixedRotationParametersdegPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.FixedRotationParametersdegPanel);
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @MainParameterChange, true);
            app.ZSpinner.Limits = [0 360];
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @MainParameterChange, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = StaticKinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StaticKinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StaticKinetUIFigure)
        end
    end
end